classdef TTGuiControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TTControl              matlab.ui.Figure
        lPsTemplate            matlab.ui.control.Label
        efDeadtimeTemplate     matlab.ui.control.NumericEditField
        capDeadtime            matlab.ui.control.Label
        lPs2Template           matlab.ui.control.Label
        capInputDelay          matlab.ui.control.Label
        efInputDelayTemplate   matlab.ui.control.NumericEditField
        capCountrate           matlab.ui.control.Label
        capTriggerLevel        matlab.ui.control.Label
        capTestsignal          matlab.ui.control.Label
        capChannel             matlab.ui.control.Label
        lCountrateTemplate     matlab.ui.control.Label
        lTriggerLevelTemplate  matlab.ui.control.Label
        sTriggerLevelTemplate  matlab.ui.control.Slider
        lChannelTemplate       matlab.ui.control.Label
        cbTestTemplate         matlab.ui.control.CheckBox
    end

    % Control window to set the basic settings for the Time Tagger channels
    % 
    % The program is a basic example of how to use the Time Tagger with a maltab gui.
    % Please modify and adjust the program the way you need it, e.g. the implementation for falling edges.    
    %
    % Hint: The channel of each gui element is stored in .UserDate of each gui element.
    
    properties (Constant)
        LINEHEIGHT = 25; % line height for each channel
    end
    
    properties (Access = private)
        timetagger           % Time Tagger object 
        countrate            % Countrate measurement class
        updateCountrateTimer % timer which polls current data of the countrate measurement
        
        % gui elements
        cbTest        % checkboxes to enable/disable the test signals
        sTriggerLevel % sliders to set the trigger levels
        lTriggerLevel % labels for the trigger levels
        lCountrate    % labels for the countrate outputs
        efInputDelay  % edit fields for the input delays
        efDeadtime    % edit fields for the dead times
        df            % number formatter object, required to add thousands separator for the count rate
        allChannels   % stores the channel numbers of all channels (first rising edge, then falling edge)
    end

    methods (Access = private)
        function updateCountrateTimerCallback(app)
        % updateTimerCallback is invoked by the updateTimer and polls the countrate.getData() to visualize the current countrate of each channel
            countrates = app.countrate.getData();
            app.countrate.clear();
            
            for i=1:length(countrates)
                app.lCountrate{i}.Text = sprintf('%s counts / s', string(app.df.format(round(countrates(i)))));
            end
        end        

        function updateTriggelLevelElements(app, channelIndex)
        % acquires the current trigger level of the channel passed as a parameter and updates the slider position and the label
            voltage = app.timetagger.getTriggerLevel(app.sTriggerLevel{channelIndex}.UserData);
            app.sTriggerLevel{channelIndex}.Value = voltage;
            app.lTriggerLevel{channelIndex}.Text = sprintf('%0.1f V', voltage);
        end
        
        function all_channels = getAllChannels(app)
            channels_rising = app.timetagger.getChannelList(TTChannelEdge.Rising);
            channels_falling = app.timetagger.getChannelList(TTChannelEdge.Falling);
            all_channels = [channels_rising flip(channels_falling)];
        end
    
        function index = getIndexFromChannel(app, channel)
            index = find(app.allChannels == channel);
        end
    
        % we have a variable number of channels, so the idea is to have a template channel in from the application builder which is replicated
        function replicateElement(app, templateName)
        % replicates gui elements which the user interacts with or which have to be modified    
            eval(['template = app.' templateName 'Template;']);
            all_channels = app.allChannels;
            vector = cell(1, length(all_channels));
            vector{1} = template;
            for i=1:length(all_channels)            
                if i == 1 
                    vector{i}.UserData = all_channels(i);
                    continue
                end
                vector{i} = copyobj(vector{1}, vector{1}.Parent);
                vector{i}.UserData = all_channels(i);
                
                if isprop(vector{1}, 'ValueChangedFcn')
                    vector{i}.ValueChangedFcn = template.ValueChangedFcn;
                end
                if isprop(vector{1}, 'ValueChangingFcn')
                    vector{i}.ValueChangingFcn = template.ValueChangingFcn;
                end
                
                vector{i}.Position(2) = vector{i-1}.Position(2) - app.LINEHEIGHT;
            end            
            eval(['app.' templateName ' = vector;']);
        end

        function replicateConstantLabel(app, templateName)
        % replicates gui elements which are static
            eval(['template = app.' templateName 'Template;']);
            all_channels = app.allChannels;
            for i=1:length(all_channels)
                if (i == 1)
                    if (strcmp(templateName, 'lChannel'))
                        app.lChannelTemplate.Text = ['ch ', num2str(all_channels(i))];
                    end
                    continue
                end
                newLabel = copyobj(template, template.Parent);
                newLabel.Position(2) = template.Position(2) - (i-1) * app.LINEHEIGHT;
                % the channel label needs to be changed according to the channel number
                if strcmp(templateName, 'lChannel')
                    newLabel.Text = sprintf('ch %d', all_channels(i));
                end
            end
        end
    
        function createUiElementsFromTemplate(app)
            allElements = {'cbTest', 'sTriggerLevel', 'lTriggerLevel', 'lCountrate', 'efInputDelay', 'efDeadtime'};
            for element = allElements
                replicateElement(app, element{1})
            end
            allLabels = {'lChannel', 'lPs', 'lPs2'};
            for element = allLabels
                replicateConstantLabel(app, element{1})
            end
        end        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % get the Time Tagger object
            app.timetagger = TimeTagger; 
            app.allChannels = app.getAllChannels();
            % in case there are more than 18 channels (rising together with falling), show only the first 18
            if length(app.allChannels) > 18
                app.allChannels = app.allChannels(1:18);
            end
            
            all_channels = app.allChannels;
            
            % create number formatter object
            pattern = '#,##0.#';
            symbols = java.text.DecimalFormatSymbols();
            symbols.setGroupingSeparator(' ')
            symbols.setDecimalSeparator('.')
            app.df = java.text.DecimalFormat(pattern, symbols);
            
            % build the gui from the template
            createUiElementsFromTemplate(app);
            
            % retrieve the current time tagger settings and update the gui elements accordingly
            for i = 1: length(all_channels)
                channel = all_channels(i);
                if (length(all_channels) == 18) || (i <= length(all_channels) / 2)
                    app.cbTest{i}.Value = app.timetagger.getTestSignal(channel);
                    updateTriggelLevelElements(app, i)
                else
                    app.cbTest{i}.Visible = 'off';
                    app.sTriggerLevel{i}.Visible = 'off';
                    app.lTriggerLevel{i}.Visible = 'off';
                end
                app.efInputDelay{i}.Value = double(app.timetagger.getInputDelay(channel));
                app.efDeadtime{i}.Value = double(app.timetagger.getDeadtime(channel));
            end
                        
            % create the countrate measurement
            app.countrate = TTCountrate(app.timetagger, all_channels);
            % create and start the poll timer which updates the countrate in the gui
            app.updateCountrateTimer = timer('StartDelay', 1, 'Period', 1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.updateCountrateTimer.TimerFcn = @(obj, event)updateCountrateTimerCallback(app);
            start(app.updateCountrateTimer)
        end

        % Value changed function: cbTestTemplate
        function testSignalChange(app, event)
            app.timetagger.setTestSignal(event.Source.UserData, event.Source.Value);
        end

        % Close request function: TTControl
        function TTControlCloseRequest(app, event)
            if ~isempty(app.updateCountrateTimer)
                stop(app.updateCountrateTimer)
            end
            delete(app)
        end

        % Value changing function: sTriggerLevelTemplate
        function sTriggerLevelTemplateValueChanging(app, event)
            triggerLevel = event.Value;
            channel = event.Source.UserData;
            app.timetagger.setTriggerLevel(channel, triggerLevel);
            updateTriggelLevelElements(app, app.getIndexFromChannel(channel));
        end

        % Value changed function: efInputDelayTemplate
        function efInputDelayTemplateValueChanged(app, event)
            value = event.Source.Value;
            app.timetagger.setInputDelay(event.Source.UserData, value);
        end

        % Value changed function: efDeadtimeTemplate
        function efDeadtimeTemplateValueChanged(app, event)
            value = event.Source.Value;
            actualDeadtime = app.timetagger.setDeadtime(event.Source.UserData, value);       
            % the deadtime can be only a multiple of the inverse clock frequency of the Time Tagger. Therefore the actual value can be different from the value typed in. The real deadtime in the input field.
            event.Source.Value = double(actualDeadtime);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TTControl = uifigure('Visible', 'off');
            app.TTControl.Name = 'Time Tagger Settings';
            app.TTControl.CloseRequestFcn = createCallbackFcn(app, @TTControlCloseRequest, true);
            app.cbTestTemplate = uicheckbox(app.TTControl);
            app.cbTestTemplate.ValueChangedFcn = createCallbackFcn(app, @testSignalChange, true);
            app.cbTestTemplate.Text = '';
            app.lChannelTemplate = uilabel(app.TTControl);
            app.lChannelTemplate.Text = 'ch 0';
            app.sTriggerLevelTemplate = uislider(app.TTControl);
            app.sTriggerLevelTemplate.Limits = [0 2.5];
            app.sTriggerLevelTemplate.MajorTicks = [];
            app.sTriggerLevelTemplate.MajorTickLabels = {};
            app.sTriggerLevelTemplate.ValueChangingFcn = createCallbackFcn(app, @sTriggerLevelTemplateValueChanging, true);
            app.sTriggerLevelTemplate.MinorTicks = [];
            app.sTriggerLevelTemplate.Value = 0.5;
            app.lTriggerLevelTemplate = uilabel(app.TTControl);
            app.lTriggerLevelTemplate.Text = '0.5 V';
            app.lCountrateTemplate = uilabel(app.TTControl);
            app.lCountrateTemplate.Text = '0 counts / s';
            app.capChannel = uilabel(app.TTControl);
            app.capChannel.Text = 'Channel';
            app.capTestsignal = uilabel(app.TTControl);
            app.capTestsignal.Text = 'Testsignal';
            app.capTriggerLevel = uilabel(app.TTControl);
            app.capTriggerLevel.Text = 'Trigger Level';
            app.capCountrate = uilabel(app.TTControl);
            app.capCountrate.Text = 'Countrate';
            app.efInputDelayTemplate = uieditfield(app.TTControl, 'numeric');
            app.efInputDelayTemplate.ValueChangedFcn = createCallbackFcn(app, @efInputDelayTemplateValueChanged, true);
            app.capInputDelay = uilabel(app.TTControl);
            app.capInputDelay.Text = 'Input Delay';
            app.lPs2Template = uilabel(app.TTControl);
            app.lPs2Template.Text = 'ps';
            app.capDeadtime = uilabel(app.TTControl);
            app.capDeadtime.Text = 'Deadtime';
            app.efDeadtimeTemplate = uieditfield(app.TTControl, 'numeric');
            app.efDeadtimeTemplate.Limits = [0 Inf];
            app.efDeadtimeTemplate.ValueChangedFcn = createCallbackFcn(app, @efDeadtimeTemplateValueChanged, true);
            app.lPsTemplate = uilabel(app.TTControl);
            app.lPsTemplate.Text = 'ps';
            % Show the figure after all components are created
            app.TTControl.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTGuiControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TTControl)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TTControl)
        end
    end
end