classdef TTGuiCorrelation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Correlation                 matlab.ui.Figure
        control                     matlab.ui.container.Panel
        bSave                       matlab.ui.control.Button
        bStop                       matlab.ui.control.Button
        bStart                      matlab.ui.control.Button
        bClear                      matlab.ui.control.Button
        settings                    matlab.ui.container.Panel
        efChannelB                  matlab.ui.control.NumericEditField
        channelBEditFieldLabel      matlab.ui.control.Label
        efChannelA                  matlab.ui.control.NumericEditField
        channelAEditFieldLabel_2    matlab.ui.control.Label
        efBins                      matlab.ui.control.NumericEditField
        nbinsEditFieldLabel         matlab.ui.control.Label
        efBinWidth                  matlab.ui.control.NumericEditField
        binwidthpsEditFieldLabel_2  matlab.ui.control.Label
        axis                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        timetagger            % Time Tagger object
        correlation           % Correlation measurement class
        plotCorrelationTimer % timer which polls current data of the Correlation measurement
    end
    
    methods (Access = private)
        function plotCorrelationTimerCallback(app)
        % retrieve the data from the Correlation measurement and plot the data    
            x = app.correlation.getIndex();
            y = app.correlation.getData();
            if size(x,2) ~= size(y,2)
                % settings have changed between the getIndex() and getData()
                return
            end
            plot(app.axis, x,y)
            maxvalue = max(y);
            if maxvalue == 0
                ylim(app.axis, [0 1])
            else
                ylim(app.axis, [0 maxvalue*1.1])
            end
        end              
        
        function updateCorrelationSettings(app)
        % create a new Correlation measurement with the current settings from the gui input fields
            app.correlation = TTCorrelation(app.timetagger, app.efChannelA.Value, app.efChannelB.Value, app.efBinWidth.Value, app.efBins.Value);
            disp(app.efBins.Value)
            disp(app.efBinWidth.Value)
            app.efBins.Value
            app.efBinWidth.Value
        end
    
        function all_channels = getAllChannels(app)
            channels_rising = app.timetagger.getChannelList(TTChannelEdge.Rising);
            channels_falling = app.timetagger.getChannelList(TTChannelEdge.Falling);
            all_channels = [channels_rising channels_falling];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a Time Tagger object
            app.timetagger = TimeTagger;
            all_channels = app.getAllChannels();
            % set the limits of the channel number edit fields
            app.efChannelA.Limits = double([min(all_channels) max(all_channels)]);
            app.efChannelB.Limits = double([min(all_channels) max(all_channels)]);
            % create a Correlation measurement
            updateCorrelationSettings(app)
            % create and start a timer polling and plotting the data of the Correlation measurement
            app.plotCorrelationTimer = timer('StartDelay', 1, 'Period', 0.2, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedRate');
            app.plotCorrelationTimer.TimerFcn = @(obj, event)plotCorrelationTimerCallback(app);
            start(app.plotCorrelationTimer)
        end

        % Button pushed function: bClear
        function bClearButtonPushed(app, event)
            app.correlation.clear();
            plotCorrelationTimerCallback(app);
        end

        % Button pushed function: bStop
        function bStopButtonPushed(app, event)
            app.correlation.stop();
            plotCorrelationTimerCallback(app);            
        end

        % Button pushed function: bStart
        function bStartButtonPushed(app, event)
            app.correlation.start();
        end

        % Close request function: Correlation
        function CorrelationCloseRequest(app, event)
            if ~isempty(app.plotCorrelationTimer)
                stop(app.plotCorrelationTimer)
            end
            delete(app)
        end

        % Value changed function: efBinWidth
        function efBinWidthValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Value changed function: efBins
        function binsValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Value changed function: efChannelA
        function channelAValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Value changed function: efChannelB
        function channelBValueChanged(app, event)
            updateCorrelationSettings(app);
        end

        % Button pushed function: bSave
        function bSaveButtonPushed(app, event)
            x = app.correlation.getIndex();
            y = app.correlation.getData();
            duration = app.correlation.getCaptureDuration();
            time = datetime;
            
            correlationMeasurement.x = x;
            correlationMeasurement.counts = y;
            correlationMeasurement.duration = duration;
            correlationMeasurement.chA = app.efChannelA.Value;
            correlationMeasurement.chB = app.efChannelB.Value;
            correlationMeasurement.bins = app.efBins.Value;
            correlationMeasurement.binWidth = app.efBinWidth.Value;
            correlationMeasurement.saveTime = time;
            
            formatOut = 'yyyy-mm-dd_HH-MM-SS';
            ds = datestr(time, formatOut);

            uisave({'correlationMeasurement'}, strcat('Correlation_', ds));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Correlation = uifigure('Visible', 'off');
            app.Correlation.Name = 'Correlation';
            app.Correlation.CloseRequestFcn = createCallbackFcn(app, @CorrelationCloseRequest, true);
            app.axis = uiaxes(app.Correlation);
            xlabel(app.axis, 'time [ns]')
            ylabel(app.axis, 'counts')
            app.settings = uipanel(app.Correlation);
            app.binwidthpsEditFieldLabel_2 = uilabel(app.settings);
            app.binwidthpsEditFieldLabel_2.Text = 'bin width [ps]';
            app.efBinWidth = uieditfield(app.settings, 'numeric');
            app.efBinWidth.Limits = [1 Inf];
            app.efBinWidth.ValueChangedFcn = createCallbackFcn(app, @efBinWidthValueChanged, true);
            app.efBinWidth.Value = 1000;
            app.nbinsEditFieldLabel = uilabel(app.settings);
            app.nbinsEditFieldLabel.Text = 'n bins';
            app.efBins = uieditfield(app.settings, 'numeric');
            app.efBins.Limits = [1 Inf];
            app.efBins.ValueChangedFcn = createCallbackFcn(app, @binsValueChanged, true);
            app.efBins.Value = 3000;
            app.channelAEditFieldLabel_2 = uilabel(app.settings);
            app.channelAEditFieldLabel_2.Text = 'channel A';
            app.efChannelA = uieditfield(app.settings, 'numeric');
            app.efChannelA.Limits = [0 Inf];
            app.efChannelA.ValueChangedFcn = createCallbackFcn(app, @channelAValueChanged, true);
            app.efChannelA.Value = 1;
            app.channelBEditFieldLabel = uilabel(app.settings);
            app.channelBEditFieldLabel.Text = 'channel B';
            app.efChannelB = uieditfield(app.settings, 'numeric');
            app.efChannelB.Limits = [0 Inf];
            app.efChannelB.ValueChangedFcn = createCallbackFcn(app, @channelBValueChanged, true);
            app.efChannelB.Value = 2;
            app.control = uipanel(app.Correlation);
            app.bClear = uibutton(app.control, 'push');
            app.bClear.ButtonPushedFcn = createCallbackFcn(app, @bClearButtonPushed, true);
            app.bClear.Text = 'Clear';
            app.bStart = uibutton(app.control, 'push');
            app.bStart.ButtonPushedFcn = createCallbackFcn(app, @bStartButtonPushed, true);
            app.bStart.Text = 'Start';
            app.bStop = uibutton(app.control, 'push');
            app.bStop.ButtonPushedFcn = createCallbackFcn(app, @bStopButtonPushed, true);
            app.bStop.Text = 'Stop';
            app.bSave = uibutton(app.control, 'push');
            app.bSave.ButtonPushedFcn = createCallbackFcn(app, @bSaveButtonPushed, true);
            app.bSave.Text = 'Save';
            % Show the figure after all components are created
            app.Correlation.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTGuiCorrelation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Correlation)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Correlation)
        end
    end
end