classdef TTGuiCounter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Counter                        matlab.ui.Figure
        ChannelsPanel                  matlab.ui.container.Panel
        cbChannelTemplate              matlab.ui.control.CheckBox
        control                        matlab.ui.container.Panel
        bSave                          matlab.ui.control.Button
        bStop                          matlab.ui.control.Button
        bStart                         matlab.ui.control.Button
        bClear                         matlab.ui.control.Button
        settings                       matlab.ui.container.Panel
        binWidth                       matlab.ui.control.NumericEditField
        integrationtimebinwidthsLabel  matlab.ui.control.Label
        bins                           matlab.ui.control.NumericEditField
        numberofdatapointsEditFieldLabel  matlab.ui.control.Label
        axis                           matlab.ui.control.UIAxes
    end

    properties (Constant)
        LINEHEIGHT = 25; % line height for each channel
    end

    properties (Access = private)
        timetagger       % Time Tagger object
        counter          % Counter measurement class
        plotCounterTimer % timer which polls current data of the Correlation measurement
        binWidthDouble   % binWidth    
        cbChannel        % checkbox array of channels
        df               % decimal formatter object, required to format numbers with thousands separator
        plot_lines       % handles of the plot lines 
    end

    methods (Access = private)
        function plotCounterTimerCallback(app)
        % retrieve the data from the Correlation measurement and plot the data
            co = get(app.axis,'ColorOrder');
            if isempty(app.counter)
                return
            end
            [x, ys] = getPlotData(app);
            if size(x,2) ~= size(ys,2)
                % settings have changed between the getIndex() and getData()
                return
            end

            for ch = 1:size(ys,1)
                app.plot_lines(ch).YData = ys(ch,:);    
            end
            
            tickVals = get(app.axis,'YTick');
            newTickLabels = thousandSep(app, tickVals);
            set(app.axis,'YTickLabel',newTickLabels)
        end
        
        function [x, ys] = getPlotData(app)
        % returns the x and y values of the plot data
            x = double(app.counter.getIndex()) / 1e12;
            ys = double(app.counter.getData()) / app.binWidthDouble * 1e12;            
        end
        
        function channelList = getActiveChannels(app)
        % returns the currently selected channels and changes the color of the label according to the plot colors
            co = get(app.axis,'ColorOrder');
            channelList = [];
            for i=1:length(app.cbChannel)
                if (app.cbChannel{i}.Value)
                    % set the color of the channel label according to the plot color
                    %   app.cbChannel{i}.FontColor = co(mod(i-1,size(co,1))+1,:); % This does not work properly, Igor 12-04-2019.
                    channelList = [channelList app.cbChannel{i}.UserData];
                else
                    app.cbChannel{i}.FontColor = [0 0 0];
                end
            end
        end
        
        function updateCounterSettings(app)
        % create a new Counter measurement with the current settings from the gui input fields
            if isempty(app.cbChannel)
                return
            end        
            channels = getActiveChannels(app);
            if isempty(channels)
                cla(app.axis);
                app.plot_lines = [];
                delete(app.counter);
                app.counter = [];
                return
            end
            
            app.binWidthDouble = app.binWidth.Value * 1e12;
            app.counter = TTCounter(app.timetagger, channels, int64(round(app.binWidthDouble)), app.bins.Value);
            
            % Create line objects
            [x, ys] = app.getPlotData();
            app.plot_lines = plot(app.axis, x, ys);
        end
        
        function out = thousandSep(app, in)
        % format a number with a thousands separator
            out = cell(1,length(in));
            for i = 1:numel(in)
                out{i} = char(app.df.format(in(i)));
            end
        end
    
        function all_channels = getAllChannels(app)
            channels_rising = app.timetagger.getChannelList(TTChannelEdge.Rising);
            channels_falling = app.timetagger.getChannelList(TTChannelEdge.Falling);
            if length(channels_rising) == 18
                all_channels = [channels_rising];
            else
                all_channels = [channels_rising flip(channels_falling)];
            end
        end
            
        function replicateChannelCheckbox(app)
        % replicates gui elements which the user interacts with or which have to be modified    
            channels = app.getAllChannels();
            template = app.cbChannelTemplate;
            vector = cell(1, length(channels));
            vector{1} = template;
            for i=1:length(channels)
                channelNo = channels(i);
                if i == 1 
                    vector{i}.UserData = channelNo;
                    vector{i}.Text = sprintf("ch %d", channelNo);
                    continue
                end
                vector{i} = copyobj(vector{1}, vector{1}.Parent);
                vector{i}.UserData = channelNo;
                vector{i}.ValueChangedFcn = template.ValueChangedFcn;
                vector{i}.Position(2) = vector{i-1}.Position(2) - app.LINEHEIGHT;
                vector{i}.Text = sprintf("ch %d", channelNo);
                vector{i}.Value = false;
            end            
            app.cbChannel = vector;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % create a Time Tagger object
            app.timetagger = TimeTagger;
            
            % create number formatter object
            pattern = '#,##0.#';
            symbols = java.text.DecimalFormatSymbols();
            symbols.setGroupingSeparator(' ')
            symbols.setDecimalSeparator('.')
            app.df = java.text.DecimalFormat(pattern, symbols);
            
            % create all channel checkboxes out of the channel template
            replicateChannelCheckbox(app)
            % create a Correlation measurement
            updateCounterSettings(app)
            % create and start a timer polling and plotting the data of the Correlation measurement
            app.plotCounterTimer = timer('StartDelay', 1, 'Period', 0.1, 'TasksToExecute', Inf, 'ExecutionMode', 'fixedSpacing');
            app.plotCounterTimer.TimerFcn = @(obj, event)plotCounterTimerCallback(app);
            start(app.plotCounterTimer)
        end

        % Button pushed function: bClear
        function bClearButtonPushed(app, event)
            app.counter.clear();
            plotCounterTimerCallback(app);
        end

        % Button pushed function: bStop
        function bStopButtonPushed(app, event)
            app.counter.stop();
            plotCounterTimerCallback(app);            
        end

        % Button pushed function: bStart
        function bStartButtonPushed(app, event)
            app.counter.start();
        end

        % Close request function: Counter
        function CounterCloseRequest(app, event)
            if ~isempty(app.plotCounterTimer)
                stop(app.plotCounterTimer)
            end
            delete(app)
        end

        % Value changed function: binWidth
        function binWidthValueChanged(app, event)
            updateCounterSettings(app);
        end

        % Value changed function: bins
        function binsValueChanged(app, event)
            updateCounterSettings(app);
        end

        % Button pushed function: bSave
        function bSaveButtonPushed(app, event)
            [x, ys] = getPlotData(app);
            duration = app.counter.getCaptureDuration();
            time = datetime;
            
            counterMeasurement.x = x;
            counterMeasurement.countrates = ys;
            counterMeasurement.duration = duration;
            counterMeasurement.channels = getActiveChannels(app);
            counterMeasurement.bins = app.bins.Value;
            counterMeasurement.binWidth = app.binWidth.Value;
            counterMeasurement.saveTime = time;
            
            formatOut = 'yyyy-mm-dd_HH-MM-SS';
            ds = datestr(time, formatOut);

            uisave({'counterMeasurement'}, strcat('Counter_', ds));
        end

        % Value changed function: cbChannelTemplate
        function cbChannelValueChanged(app, event)
            updateCounterSettings(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Counter = uifigure('Visible', 'off');
            app.Counter.Name = 'Counter';
            app.Counter.CloseRequestFcn = createCallbackFcn(app, @CounterCloseRequest, true);
            app.axis = uiaxes(app.Counter);
            xlabel(app.axis, 'time [s]')
            ylabel(app.axis, 'count rate [counts / s]')
            app.settings = uipanel(app.Counter);
            app.numberofdatapointsEditFieldLabel = uilabel(app.settings);
            app.numberofdatapointsEditFieldLabel.Text = 'number of data points';
            app.bins = uieditfield(app.settings, 'numeric');
            app.bins.Limits = [1 Inf];
            app.bins.ValueChangedFcn = createCallbackFcn(app, @binsValueChanged, true);
            app.bins.Value = 200;
            app.integrationtimebinwidthsLabel = uilabel(app.settings);
            app.integrationtimebinwidthsLabel.Text = 'integration time (binwidth) [s]';
            app.binWidth = uieditfield(app.settings, 'numeric');
            app.binWidth.Limits = [1e-12 Inf];
            app.binWidth.ValueChangedFcn = createCallbackFcn(app, @binWidthValueChanged, true);
            app.binWidth.Value = 0.1;
            app.control = uipanel(app.Counter);
            app.bClear = uibutton(app.control, 'push');
            app.bClear.ButtonPushedFcn = createCallbackFcn(app, @bClearButtonPushed, true);
            app.bClear.Text = 'Clear';
            app.bStart = uibutton(app.control, 'push');
            app.bStart.ButtonPushedFcn = createCallbackFcn(app, @bStartButtonPushed, true);
            app.bStart.Text = 'Start';
            app.bStop = uibutton(app.control, 'push');
            app.bStop.ButtonPushedFcn = createCallbackFcn(app, @bStopButtonPushed, true);
            app.bStop.Text = 'Stop';
            app.bSave = uibutton(app.control, 'push');
            app.bSave.ButtonPushedFcn = createCallbackFcn(app, @bSaveButtonPushed, true);
            app.bSave.Text = 'Save';
            app.ChannelsPanel = uipanel(app.Counter);
            app.ChannelsPanel.Title = 'Channels';
            app.cbChannelTemplate = uicheckbox(app.ChannelsPanel);
            app.cbChannelTemplate.ValueChangedFcn = createCallbackFcn(app, @cbChannelValueChanged, true);
            app.cbChannelTemplate.Text = 'ch 0';
            app.cbChannelTemplate.Value = true;
            % Show the figure after all components are created
            app.Counter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTGuiCounter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Counter)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Counter)
        end
    end
end